classdef p4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Image              matlab.ui.control.Image
        rowEditFieldLabel  matlab.ui.control.Label
        rowEditField       matlab.ui.control.NumericEditField
        colEditFieldLabel  matlab.ui.control.Label
        colEditField       matlab.ui.control.NumericEditField
        Button             matlab.ui.control.Button
        Label              matlab.ui.control.Label
        Image2             matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            PG=imread('crane.jpg');
            app.Image.ImageSource=PG;
            r=PG(:,:,1);
            g=PG(:,:,2);
            b=PG(:,:,3);
            ADD=r-(g+b);
            max_r=max(max(ADD));
            [row,col]=find(ADD==max_r);
            app.rowEditField.Value=row;
            app.colEditField.Value=col;
            % The Violet is [127,0,255]
            PG13=PG;
            L=10;
            PG13(row-L/2:row+L/2,col-L/2:col+L/2,1)=127;
            PG13(row-L/2:row+L/2,col-L/2:col+L/2,2)=0;
            PG13(row-L/2:row+L/2,col-L/2:col+L/2,3)=255;
            app.Image2.ImageSource=PG13;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.rowEditFieldLabel = uilabel(app.UIFigure);
            app.rowEditFieldLabel.Text = 'row';
            app.rowEditField = uieditfield(app.UIFigure, 'numeric');
            app.colEditFieldLabel = uilabel(app.UIFigure);
            app.colEditFieldLabel.Text = 'col';
            app.colEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '王宇涵2220213666';
            app.Image2 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end